classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LeftGridLayout             matlab.ui.container.GridLayout
        SettingsLabel              matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        VisionTab                  matlab.ui.container.Tab
        VisionGridLayout           matlab.ui.container.GridLayout
        AquireImageButton          matlab.ui.control.Button
        ZDepthEditFieldLabel       matlab.ui.control.Label
        ZDepthEditField            matlab.ui.control.NumericEditField
        CalculatePointsButton      matlab.ui.control.Button
        ManualStartGuessButton     matlab.ui.control.Button
        AutoStartGuessButton       matlab.ui.control.Button
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        PathingTab                 matlab.ui.container.Tab
        PathingGridLayout          matlab.ui.container.GridLayout
        DownsampleEditFieldLabel   matlab.ui.control.Label
        DownsampleEditField        matlab.ui.control.NumericEditField
        MaxDistanceEditFieldLabel  matlab.ui.control.Label
        MaxDistanceEditField       matlab.ui.control.NumericEditField
        AveragingEditFieldLabel    matlab.ui.control.Label
        AveragingEditField         matlab.ui.control.NumericEditField
        GeneratePathButton         matlab.ui.control.Button
        SmoothingEditFieldLabel    matlab.ui.control.Label
        SmoothingEditField         matlab.ui.control.NumericEditField
        SamplesEditFieldLabel      matlab.ui.control.Label
        SamplesEditField           matlab.ui.control.NumericEditField
        ResampleButton             matlab.ui.control.Button
        PointsEditFieldLabel       matlab.ui.control.Label
        PointsEditField            matlab.ui.control.NumericEditField
        DownsampleButton           matlab.ui.control.Button
        ShowSplineButton           matlab.ui.control.Button
        RMRCTab                    matlab.ui.container.Tab
        RMRCGridLayout             matlab.ui.container.GridLayout
        CollisionCheckSwitchLabel  matlab.ui.control.Label
        CollisionCheckSwitch       matlab.ui.control.Switch
        LoadTrajectoryButton       matlab.ui.control.Button
        ReinitialiseButton         matlab.ui.control.Button
        RenderCollisionEllipsoidsSwitchLabel  matlab.ui.control.Label
        RenderCollisionEllipsoidsSwitch  matlab.ui.control.Switch
        RightPanel                 matlab.ui.container.Panel
        RightGridLayout            matlab.ui.container.GridLayout
        RightTabGroup              matlab.ui.container.TabGroup
        AxesTab                    matlab.ui.container.Tab
        AxisGridLayout             matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
        ControlTab                 matlab.ui.container.Tab
        ControlGridLayout          matlab.ui.container.GridLayout
        ControlLabel               matlab.ui.control.Label
        EStopButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        DobotStatsPanel            matlab.ui.container.Panel
        DobotJointsGridLayout      matlab.ui.container.GridLayout
        Joint1EditFieldLabel       matlab.ui.control.Label
        DobotJoint1EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        DobotJoint2EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        DobotJoint3EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel       matlab.ui.control.Label
        DobotJoint4EditField       matlab.ui.control.NumericEditField
        Joint5EditFieldLabel       matlab.ui.control.Label
        DobotJoint5EditField       matlab.ui.control.NumericEditField
        ARTagsPositionsPanel       matlab.ui.container.Panel
        ARGridLayout               matlab.ui.container.GridLayout
        PathEditFieldLabel         matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        CameraEditFieldLabel       matlab.ui.control.Label
        CameraEditField            matlab.ui.control.EditField
        DobotEditFieldLabel        matlab.ui.control.Label
        DobotEditField             matlab.ui.control.EditField
        LogTextArea                matlab.ui.control.TextArea
        TeachTab                   matlab.ui.container.Tab
        TeachGridLayout            matlab.ui.container.GridLayout
        ManualJoggingPanel         matlab.ui.container.Panel
        ManualJoggingGridLayout    matlab.ui.container.GridLayout
        XSpinnerLabel              matlab.ui.control.Label
        XSpinner                   matlab.ui.control.Spinner
        YSpinnerLabel              matlab.ui.control.Label
        YSpinner                   matlab.ui.control.Spinner
        ZSpinnerLabel              matlab.ui.control.Label
        ZSpinner                   matlab.ui.control.Spinner
        JointPositionsPanel        matlab.ui.container.Panel
        JointPositionGridLayout    matlab.ui.container.GridLayout
        LinearRailSliderLabel      matlab.ui.control.Label
        LinearRailSlider           matlab.ui.control.Slider
        BaseSliderLabel            matlab.ui.control.Label
        BaseSlider                 matlab.ui.control.Slider
        ElbowSliderLabel           matlab.ui.control.Label
        ElbowSlider                matlab.ui.control.Slider
        EEServoSliderLabel         matlab.ui.control.Label
        EEServoSlider              matlab.ui.control.Slider
        ShoulderSliderLabel        matlab.ui.control.Label
        ShoulderSlider             matlab.ui.control.Slider
        RefreshButton              matlab.ui.control.Button
        StatusGridLayout           matlab.ui.container.GridLayout
        StatusIndicatorLampLabel   matlab.ui.control.Label
        StatusIndicatorLamp        matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        intialised % Only run robot once path has been set
        rosMode logical
        controller Controller % System Controller
        controlTimer timer % Timer object to auto refresh the control values
    end
    
    methods (Access = private)
        function SetStatus(app, code)
            switch(lower(code))
                case 'environmentnotsetup'
                    colour = 'yellow';
                    text = 'Trajectory not loaded';
                case 'environmentsetup'
                    colour = 'green';
                    text = 'Trajectory loaded';
                case 'running'
                    colour = 'green';
                    text = 'Dobot running';
                case 'reset'
                    colour = 'yellow';
                    text = 'Environment reset';
                case 'estop'
                    colour = 'red';
                    text = 'E Stop triggered, reset to continue';
                case 'pathfound'
                    colour = 'green';
                    text = 'Path found';
                case 'nopath'
                    colour = 'red';
                    text = 'No path found';
                case 'collision'
                    colour = 'red';
                    text = 'A collision had occured, reset to continue';
                otherwise
                    colour = 'magenta';
                    text = 'Unknown status code';
            end
            app.StatusIndicatorLamp.Color = colour;
            app.StatusIndicatorLampLabel.Text = text;
        end
        
        function ResetAxis(app)
            cla(app.UIAxes,'reset');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
            app.UIAxes.NextPlot = 'replace';
        end
        
        function UpdateStartGuessField(app)
            startGuess = app.controller.GetPathStartGuess();
            app.XEditField.Value = startGuess(1);
            app.YEditField.Value = startGuess(2);
            app.ZEditField.Value = startGuess(3);
        end
        
        function ControlTimerCreate(app)
            app.controlTimer = timer("ExecutionMode","fixedRate","Period",0.5);
            app.controlTimer.TimerFcn = {@app.GetControlTimer};
            start(app.controlTimer);
        end
        
        function GetControlTimer(app, ~, ~)
            joints = app.controller.GetDobotJoints();
            joints = [joints(1), rad2deg(joints(2:5))];
            app.DobotJoint1EditField.Value = joints(1);
            app.DobotJoint2EditField.Value = joints(2);
            app.DobotJoint3EditField.Value = joints(3);
            app.DobotJoint4EditField.Value = joints(4);
            app.DobotJoint5EditField.Value = joints(5);
                        
            [robot,game] = app.controller.GetPoses();
            robotPosition = transl(robot);
            gamePosition = transl(game);
            app.PathEditField.Value = num2str(gamePosition');
            app.CameraEditField.Value = num2str([0.0,0.0,0.0]);
            app.DobotEditField.Value = num2str(robotPosition');
            
        end
        
        function RefreshJointTeach(app)
            joints = app.controller.GetDobotJoints();
            joints = [joints(1), rad2deg(joints(2:5))];
            app.LinearRailSlider.Value = joints(1);
            app.BaseSlider.Value = joints(2);
            app.ShoulderSlider.Value = joints(3);
            app.ElbowSlider.Value = joints(4);
            app.EEServoSlider.Value = joints(5);
        end
        
        function RefreshJogTeach(app)
            [x,y,z] = app.controller.GetDobotPose();
            app.XSpinner.Value = x;
            app.YSpinner.Value = y;
            app.ZSpinner.Value = z;
        end
        
        function TeachDobot(app)
            q(1) = app.LinearRailSlider.Value;
            q(2) = app.BaseSlider.Value;
            q(3) = app.ShoulderSlider.Value;
            q(4) = app.ElbowSlider.Value;
            q(5) = app.EEServoSlider.Value;
            q(2:5) = deg2rad(q(2:5));
            app.controller.SetDobotJoints(q);
            app.RefreshJogTeach();
        end
        
        function TeachDobotLimits(app)
            qlim = app.controller.GetDobotJointLimits();
            app.LinearRailSlider.Limits = qlim(1,:);
            app.BaseSlider.Limits = rad2deg(qlim(2,:));
            app.ShoulderSlider.Limits = rad2deg(qlim(3,:));
            app.ElbowSlider.Limits = rad2deg(qlim(4,:));
            app.EEServoSlider.Limits = rad2deg(qlim(5,:));
        end
        
        function JogDobot(app)
            x = app.XSpinner.Value;
            y = app.YSpinner.Value;
            z = app.ZSpinner.Value;
            [success, ~] = app.controller.JogDobot(x,y,z);
            if success == false
                app.SetStatus('collision');
            end
            app.RefreshJointTeach();
        end
        
        function ToggleCollisions(app)
            if strcmp(app.CollisionCheckSwitch.Value,'true')
                checkCollisions = true;
            else
                checkCollisions = false;
            end
            if strcmp(app.RenderCollisionEllipsoidsSwitch.Value,'true')
                renderCollisions = true;
            else
                renderCollisions = false;
            end
            
            app.controller.ToggleCollisions(checkCollisions,renderCollisions);
        end
    end
    
    methods (Access = public)
        function SetManualStartGuess(app, startGuess)
            app.controller.UpdatePathStartGuess(startGuess);
            app.UpdateStartGuessField();
        end
        function SetPathingParameters(app, downsample, maxDistance, averaging, smoothing)
            app.DownsampleEditField.Value = downsample;
            app.MaxDistanceEditField.Value = maxDistance;
            app.AveragingEditField.Value = averaging;
            app.SmoothingEditField.Value = smoothing;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ros, debug)
            if ~exist('ros','var')
                ros = false;
            end
            if ~exist('debug','var')
                debug = false;
            end
            app.rosMode = ros;
            app.controller = Controller(ros, debug);
            app.ToggleCollisions();
            app.controller.Initialise();
            app.UpdateStartGuessField();
            app.ControlTimerCreate();
            app.TeachDobotLimits();
            app.RefreshJogTeach();
            app.RefreshJointTeach();
            cla(app.UIAxes,'reset');
            app.SetStatus('environmentnotsetup')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {267, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AquireImageButton
        function AquireImageButtonPushed(app, event)
            app.controller.AcquireImageMask();
            [~, game] = app.controller.GetPoses();
            app.ZDepthEditField.Value = game(3,4);
            app.CalculatePointsButton.Enable = true;
            app.ResetAxis();
            app.controller.ShowPathImage(app.UIAxes);
        end

        % Button pushed function: CalculatePointsButton
        function CalculatePointsButtonPushed(app, event)
            zDepth = app.ZDepthEditField.Value;
            if zDepth > 0
                points = app.controller.GeneratePathPoints(zDepth);
            else
                points = app.controller.GeneratePathPoints();
            end
            app.PointsEditField.Value = length(points);
            app.AutoStartGuessButton.Enable = true;
            app.ManualStartGuessButton.Enable = true;
            app.ResetAxis();
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: DownsampleButton
        function DownsampleButtonPushed(app, event)
            downsample = app.DownsampleEditField.Value;
            app.controller.DownsamplePathPoints(downsample)
            app.ResetAxis();
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            maxDistance = app.MaxDistanceEditField.Value;
            averaging = app.AveragingEditField.Value;
            smoothing = app.SmoothingEditField.Value;
            success = app.controller.GeneratePath(maxDistance, averaging, smoothing);
            if success == true
                app.ShowSplineButton.Enable = true;
                app.ResampleButton.Enable = true;
                app.ResetAxis();
                app.controller.ShowPathPointCloud(app.UIAxes);
                app.controller.ShowPathSpline(app.UIAxes);
                app.SetStatus('pathfound');
            else
                app.SetStatus('nopath');
            end
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            resample = app.SamplesEditField.Value;
            app.controller.GetTrajectory(resample);
            app.LoadTrajectoryButton.Enable = true;
        end

        % Button pushed function: LoadTrajectoryButton
        function LoadTrajectoryButtonPushed(app, event)
            app.controller.LoadTrajectory();
            app.StartButton.Enable = true;
            app.ReinitialiseButton.Enable = true;
            app.SetStatus('environmentsetup')
        end

        % Button pushed function: ShowSplineButton
        function ShowSplineButtonPushed(app, event)
            app.ResetAxis();
            app.controller.ShowPathSpline(app.UIAxes);
        end

        % Button pushed function: AutoStartGuessButton
        function AutoStartGuessButtonPushed(app, event)
            app.controller.UpdatePathStartGuess();
            app.UpdateStartGuessField();
            app.DownsampleButton.Enable = true;
            app.GeneratePathButton.Enable = true;
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: ManualStartGuessButton
        function ManualStartGuessButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            app.controller.UpdatePathStartGuess([x,y,z]);
            app.UpdateStartGuessField();
            app.DownsampleButton.Enable = true;
            app.GeneratePathButton.Enable = true;
            app.controller.ShowPathPointCloud(app.UIAxes);
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.controller.EStop()
            app.SetStatus('estop')
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.controller.Run();
            app.SetStatus('running')
        end

        % Button pushed function: ReinitialiseButton
        function ReinitialiseButtonPushed(app, event)
            app.controller.Initialise();
            app.SetStatus('reset')
            app.RefreshJogTeach();
            app.RefreshJointTeach();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.controlTimer);
                delete(app.controlTimer);
                app.controller.EStop();
            end
            delete(app)
        end

        % Value changed function: LinearRailSlider
        function LinearRailSliderValueChanged(app, event)
            app.TeachDobot();
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            app.TeachDobot();
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            app.TeachDobot();
        end

        % Value changed function: ElbowSlider
        function ElbowSliderValueChanged(app, event)
            app.TeachDobot();
        end

        % Value changed function: EEServoSlider
        function EEServoSliderValueChanged(app, event)
            app.TeachDobot();
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            app.JogDobot();
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            app.JogDobot();            
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            app.JogDobot();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.RefreshJointTeach();
            app.RefreshJogTeach();
        end

        % Value changed function: RenderCollisionEllipsoidsSwitch
        function RenderCollisionEllipsoidsSwitchValueChanged(app, event)
            app.ToggleCollisions();
        end

        % Value changed function: CollisionCheckSwitch
        function CollisionCheckSwitchValueChanged(app, event)
            app.ToggleCollisions();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGridLayout = uigridlayout(app.LeftPanel);
            app.SettingsLabel = uilabel(app.LeftGridLayout);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.LeftGridLayout);
            app.VisionTab = uitab(app.TabGroup);
            app.VisionTab.Title = 'Vision';
            app.VisionGridLayout = uigridlayout(app.VisionTab);
            app.AquireImageButton = uibutton(app.VisionGridLayout, 'push');
            app.AquireImageButton.ButtonPushedFcn = createCallbackFcn(app, @AquireImageButtonPushed, true);
            app.AquireImageButton.Text = 'Aquire Image';
            app.ZDepthEditFieldLabel = uilabel(app.VisionGridLayout);
            app.ZDepthEditFieldLabel.Text = 'Z Depth';
            app.ZDepthEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.CalculatePointsButton = uibutton(app.VisionGridLayout, 'push');
            app.CalculatePointsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePointsButtonPushed, true);
            app.CalculatePointsButton.Enable = 'off';
            app.CalculatePointsButton.Text = 'Calculate Points';
            app.ManualStartGuessButton = uibutton(app.VisionGridLayout, 'push');
            app.ManualStartGuessButton.ButtonPushedFcn = createCallbackFcn(app, @ManualStartGuessButtonPushed, true);
            app.ManualStartGuessButton.Enable = 'off';
            app.ManualStartGuessButton.Text = 'Manual Start Guess';
            app.AutoStartGuessButton = uibutton(app.VisionGridLayout, 'push');
            app.AutoStartGuessButton.ButtonPushedFcn = createCallbackFcn(app, @AutoStartGuessButtonPushed, true);
            app.AutoStartGuessButton.Enable = 'off';
            app.AutoStartGuessButton.Text = 'Auto Start Guess';
            app.XEditFieldLabel = uilabel(app.VisionGridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.YEditFieldLabel = uilabel(app.VisionGridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.ZEditFieldLabel = uilabel(app.VisionGridLayout);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.VisionGridLayout, 'numeric');
            app.PathingTab = uitab(app.TabGroup);
            app.PathingTab.Title = 'Pathing';
            app.PathingGridLayout = uigridlayout(app.PathingTab);
            app.DownsampleEditFieldLabel = uilabel(app.PathingGridLayout);
            app.DownsampleEditFieldLabel.Text = 'Downsample';
            app.DownsampleEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.DownsampleEditField.Value = 200;
            app.MaxDistanceEditFieldLabel = uilabel(app.PathingGridLayout);
            app.MaxDistanceEditFieldLabel.Text = 'Max Distance';
            app.MaxDistanceEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.MaxDistanceEditField.Value = 0.1;
            app.AveragingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.AveragingEditFieldLabel.Text = 'Averaging';
            app.AveragingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.AveragingEditField.Value = 4;
            app.GeneratePathButton = uibutton(app.PathingGridLayout, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Enable = 'off';
            app.GeneratePathButton.Text = 'Generate Path';
            app.SmoothingEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SmoothingEditField.Value = 0.5;
            app.SamplesEditFieldLabel = uilabel(app.PathingGridLayout);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.SamplesEditField.Value = 200;
            app.ResampleButton = uibutton(app.PathingGridLayout, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Enable = 'off';
            app.ResampleButton.Text = 'Resample';
            app.PointsEditFieldLabel = uilabel(app.PathingGridLayout);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.PathingGridLayout, 'numeric');
            app.PointsEditField.ValueDisplayFormat = '%e';
            app.PointsEditField.Editable = 'off';
            app.DownsampleButton = uibutton(app.PathingGridLayout, 'push');
            app.DownsampleButton.ButtonPushedFcn = createCallbackFcn(app, @DownsampleButtonPushed, true);
            app.DownsampleButton.Enable = 'off';
            app.DownsampleButton.Text = 'Downsample';
            app.ShowSplineButton = uibutton(app.PathingGridLayout, 'push');
            app.ShowSplineButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSplineButtonPushed, true);
            app.ShowSplineButton.Enable = 'off';
            app.ShowSplineButton.Text = 'Show Spline';
            app.RMRCTab = uitab(app.TabGroup);
            app.RMRCTab.Title = 'RMRC';
            app.RMRCGridLayout = uigridlayout(app.RMRCTab);
            app.CollisionCheckSwitchLabel = uilabel(app.RMRCGridLayout);
            app.CollisionCheckSwitchLabel.Text = 'Collision Check';
            app.CollisionCheckSwitch = uiswitch(app.RMRCGridLayout, 'slider');
            app.CollisionCheckSwitch.ItemsData = {'false', 'true'};
            app.CollisionCheckSwitch.ValueChangedFcn = createCallbackFcn(app, @CollisionCheckSwitchValueChanged, true);
            app.CollisionCheckSwitch.Value = 'true';
            app.LoadTrajectoryButton = uibutton(app.RMRCGridLayout, 'push');
            app.LoadTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrajectoryButtonPushed, true);
            app.LoadTrajectoryButton.Enable = 'off';
            app.LoadTrajectoryButton.Text = 'Load Trajectory';
            app.ReinitialiseButton = uibutton(app.RMRCGridLayout, 'push');
            app.ReinitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitialiseButtonPushed, true);
            app.ReinitialiseButton.Text = 'Reinitialise';
            app.RenderCollisionEllipsoidsSwitchLabel = uilabel(app.RMRCGridLayout);
            app.RenderCollisionEllipsoidsSwitchLabel.Text = 'Render Collision Ellipsoids';
            app.RenderCollisionEllipsoidsSwitch = uiswitch(app.RMRCGridLayout, 'slider');
            app.RenderCollisionEllipsoidsSwitch.ItemsData = {'false', 'true'};
            app.RenderCollisionEllipsoidsSwitch.ValueChangedFcn = createCallbackFcn(app, @RenderCollisionEllipsoidsSwitchValueChanged, true);
            app.RenderCollisionEllipsoidsSwitch.Value = 'false';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGridLayout = uigridlayout(app.RightPanel);
            app.RightTabGroup = uitabgroup(app.RightGridLayout);
            app.AxesTab = uitab(app.RightTabGroup);
            app.AxesTab.Title = 'Axes';
            app.AxisGridLayout = uigridlayout(app.AxesTab);
            app.UIAxes = uiaxes(app.AxisGridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.ControlTab = uitab(app.RightTabGroup);
            app.ControlTab.Title = 'Control';
            app.ControlGridLayout = uigridlayout(app.ControlTab);
            app.ControlLabel = uilabel(app.ControlGridLayout);
            app.ControlLabel.Text = 'Control';
            app.EStopButton = uibutton(app.ControlGridLayout, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E Stop';
            app.StartButton = uibutton(app.ControlGridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DobotStatsPanel = uipanel(app.ControlGridLayout);
            app.DobotStatsPanel.Title = 'Dobot Stats';
            app.DobotJointsGridLayout = uigridlayout(app.DobotStatsPanel);
            app.Joint1EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.DobotJoint1EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint1EditField.Editable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.DobotJoint2EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint2EditField.Editable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.DobotJoint3EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint3EditField.Editable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.DobotJoint4EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint4EditField.Editable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.DobotJointsGridLayout);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.DobotJoint5EditField = uieditfield(app.DobotJointsGridLayout, 'numeric');
            app.DobotJoint5EditField.Editable = 'off';
            app.ARTagsPositionsPanel = uipanel(app.ControlGridLayout);
            app.ARTagsPositionsPanel.Title = 'AR Tags Positions';
            app.ARGridLayout = uigridlayout(app.ARTagsPositionsPanel);
            app.PathEditFieldLabel = uilabel(app.ARGridLayout);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.ARGridLayout, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = 'N/A';
            app.CameraEditFieldLabel = uilabel(app.ARGridLayout);
            app.CameraEditFieldLabel.Text = 'Camera';
            app.CameraEditField = uieditfield(app.ARGridLayout, 'text');
            app.CameraEditField.Editable = 'off';
            app.CameraEditField.Value = 'N/A';
            app.DobotEditFieldLabel = uilabel(app.ARGridLayout);
            app.DobotEditFieldLabel.Text = 'Dobot';
            app.DobotEditField = uieditfield(app.ARGridLayout, 'text');
            app.DobotEditField.Editable = 'off';
            app.DobotEditField.Value = 'N/A';
            app.LogTextArea = uitextarea(app.ControlGridLayout);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Tooltip = {'Execution Log'};
            app.TeachTab = uitab(app.RightTabGroup);
            app.TeachTab.Title = 'Teach';
            app.TeachGridLayout = uigridlayout(app.TeachTab);
            app.ManualJoggingPanel = uipanel(app.TeachGridLayout);
            app.ManualJoggingPanel.Title = 'Manual Jogging';
            app.ManualJoggingGridLayout = uigridlayout(app.ManualJoggingPanel);
            app.XSpinnerLabel = uilabel(app.ManualJoggingGridLayout);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.ManualJoggingGridLayout);
            app.XSpinner.Step = 0.02;
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.ManualJoggingGridLayout);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.ManualJoggingGridLayout);
            app.YSpinner.Step = 0.02;
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.ManualJoggingGridLayout);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.ManualJoggingGridLayout);
            app.ZSpinner.Step = 0.02;
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.JointPositionsPanel = uipanel(app.TeachGridLayout);
            app.JointPositionsPanel.Title = 'Joint Positions';
            app.JointPositionGridLayout = uigridlayout(app.JointPositionsPanel);
            app.LinearRailSliderLabel = uilabel(app.JointPositionGridLayout);
            app.LinearRailSliderLabel.Text = 'Linear Rail';
            app.LinearRailSlider = uislider(app.JointPositionGridLayout);
            app.LinearRailSlider.ValueChangedFcn = createCallbackFcn(app, @LinearRailSliderValueChanged, true);
            app.BaseSliderLabel = uilabel(app.JointPositionGridLayout);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.JointPositionGridLayout);
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.ElbowSliderLabel = uilabel(app.JointPositionGridLayout);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.JointPositionGridLayout);
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowSliderValueChanged, true);
            app.EEServoSliderLabel = uilabel(app.JointPositionGridLayout);
            app.EEServoSliderLabel.Text = 'EE Servo';
            app.EEServoSlider = uislider(app.JointPositionGridLayout);
            app.EEServoSlider.ValueChangedFcn = createCallbackFcn(app, @EEServoSliderValueChanged, true);
            app.ShoulderSliderLabel = uilabel(app.JointPositionGridLayout);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.JointPositionGridLayout);
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.RefreshButton = uibutton(app.TeachGridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StatusGridLayout = uigridlayout(app.RightGridLayout);
            app.StatusIndicatorLampLabel = uilabel(app.StatusGridLayout);
            app.StatusIndicatorLampLabel.Text = 'Status Indicator';
            app.StatusIndicatorLamp = uilamp(app.StatusGridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end